classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        YellowRunningLampLabel          matlab.ui.control.Label
        YellowRunningLamp               matlab.ui.control.Lamp
        ToseeifIcanaddanindicatorLabel  matlab.ui.control.Label
        SaveArrayButton                 matlab.ui.control.Button
        AddRowButton                    matlab.ui.control.Button
        DropDown2                       matlab.ui.control.DropDown
        Button                          matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        TablehasnotbeenupdatedLabel     matlab.ui.control.Label
        DropDownLabel                   matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        LoadPreSavedArrayPanel          matlab.ui.container.Panel
        LoadArrayButton                 matlab.ui.control.Button
        Tab2                            matlab.ui.container.Tab
        MarginalsPanel                  matlab.ui.container.Panel
        x1CheckBox                      matlab.ui.control.CheckBox
        x2CheckBox                      matlab.ui.control.CheckBox
        x3CheckBox                      matlab.ui.control.CheckBox
        SelectSpeciesLabel              matlab.ui.control.Label
        PlotMarginalsButton             matlab.ui.control.Button
        SelectPlotsLabel                matlab.ui.control.Label
        ContourCheckBox                 matlab.ui.control.CheckBox
        StairsCheckBox                  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        Tablehasnotbeenupdated % Description
    end

    properties (Access = public)
        NewTable % Description
        model_directory
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            v = dir('Models');
            v = v(3:end);
            app.DropDown.Items = {v.name};
        end

        % Button pushed function: SaveArrayButton
        function SaveArrayButtonPushed(app, event)
            app.SaveArrayButton.BackgroundColor = 'y';
            prompt = {'Name file here: (No spaces and in .dat file format)'};
            title = 'Save Array As';
            dims = [1 35];
            definput = {'My_new_array.dat'};
            answer = inputdlg(prompt,title,dims,definput);
            str = string(answer);
            Mytable = app.UITable.Data;
            str_table = string(Mytable);
            FileID = fopen(str,'w');
            formatSpec = '%s %s %s %s\n';
            [nrows,ncols] = size(str_table);
            for row = 1:nrows
                fprintf(FileID,formatSpec,str_table(row,:));
            end
            fclose(FileID);
            app.SaveArrayButton.BackgroundColor = 'g';

        end

        % Callback function
        function NameArrayEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.NameArrayEditField.Value = changingValue;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
           app.TablehasnotbeenupdatedLabel.Text = 'Table has not been updated.';
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
%             keyboard
%             value2 = {value};
            app.model_directory = [];
            v2 = [];
            app.UITable.Data = [];
            if value == "app_practice_table_default.m"
                app.model_directory = 1;
                v2 = {'Default 1','Default 2'};
            elseif value == "app_practice_table_default2.m"
                app.model_directory = 2;
                v2 = {'Default 3', 'Default 4'};
            end
            
            app.DropDown2.Items = v2;
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            N = size(app.UITable.Data,1);
            TMP = app.UITable.Data;
            TMP{N+1,1} = num2str(N+1);
            TMP(N+1,1) = {''};
            TMP(N+1,2) = {''};
            TMP(N+1,3) = {''};
            TMP(N+1,4) = {''};
            app.UITable.Data = TMP;
        end

        % Button pushed function: LoadArrayButton
        function LoadArrayButtonPushed(app, event)
            Array_file = uigetfile('*.dat');
            delimiter = ' ';
            formatSpec = '%s%s%s%s%[^\n\r]';
            fileID = fopen(Array_file,'r');
            dataArray = textscan(fileID, formatSpec, 'Delimiter', delimiter, 'MultipleDelimsAsOne', true, 'TextType', 'string',  'ReturnOnError', false);
            fclose(fileID);
            Array_table = table(dataArray{1:end-1})
            Array_array = string(table2array(Array_table))
            Array_load = cellstr(Array_array)
            app.UITable.Data = Array_load
            
        end

        % Value changed function: DropDown2
        function DropDown2ValueChanged(app, event)
            value = app.DropDown2.Value;
           if app.model_directory == 1
               app_practice_table_default(value,app)
           elseif app.model_directory == 2
               app_practice_table_default2(value,app)
           end
%            app.UITable.Data = TMP;
           
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
%             Preset_Model_selection = uigetdir('Models');
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.YellowRunningLampLabel = uilabel(app.Tab);
            app.YellowRunningLampLabel.Text = 'Yellow = Running';
            app.YellowRunningLamp = uilamp(app.Tab);
            app.ToseeifIcanaddanindicatorLabel = uilabel(app.Tab);
            app.ToseeifIcanaddanindicatorLabel.Text = 'To see if I can add an indicator';
            app.SaveArrayButton = uibutton(app.Tab, 'push');
            app.SaveArrayButton.ButtonPushedFcn = createCallbackFcn(app, @SaveArrayButtonPushed, true);
            app.SaveArrayButton.Text = 'Save Array';
            app.AddRowButton = uibutton(app.Tab, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'Add Row';
            app.DropDown2 = uidropdown(app.Tab);
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.TablehasnotbeenupdatedLabel = uilabel(app.Tab);
            app.TablehasnotbeenupdatedLabel.Text = 'Table has not been updated Label';
            app.DropDownLabel = uilabel(app.Tab);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.Tab);
            app.DropDown.Items = {'Default', 'Default 2', 'Option 3', 'Option 4'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Default';
            app.LoadPreSavedArrayPanel = uipanel(app.Tab);
            app.LoadPreSavedArrayPanel.Title = 'Load Pre-Saved Array';
            app.LoadArrayButton = uibutton(app.LoadPreSavedArrayPanel, 'push');
            app.LoadArrayButton.ButtonPushedFcn = createCallbackFcn(app, @LoadArrayButtonPushed, true);
            app.LoadArrayButton.Text = 'Load Array';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.MarginalsPanel = uipanel(app.Tab2);
            app.MarginalsPanel.Title = 'Marginals';
            app.x1CheckBox = uicheckbox(app.MarginalsPanel);
            app.x1CheckBox.Text = ' x1';
            app.x2CheckBox = uicheckbox(app.MarginalsPanel);
            app.x2CheckBox.Text = ' x2';
            app.x3CheckBox = uicheckbox(app.MarginalsPanel);
            app.x3CheckBox.Text = ' x3';
            app.SelectSpeciesLabel = uilabel(app.MarginalsPanel);
            app.SelectSpeciesLabel.Text = 'Select Species';
            app.PlotMarginalsButton = uibutton(app.MarginalsPanel, 'push');
            app.PlotMarginalsButton.Text = 'Plot Marginals';
            app.SelectPlotsLabel = uilabel(app.MarginalsPanel);
            app.SelectPlotsLabel.Text = 'Select Plots';
            app.ContourCheckBox = uicheckbox(app.MarginalsPanel);
            app.ContourCheckBox.Text = 'Contour';
            app.StairsCheckBox = uicheckbox(app.MarginalsPanel);
            app.StairsCheckBox.Text = 'Stairs';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end